classdef Experiment1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CaseinformationPanel        matlab.ui.container.Panel
        DetailsLabel                matlab.ui.control.Label
        TituloLabel                 matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Timer                       matlab.ui.control.Label
        WGPanel                     matlab.ui.container.Panel
        WG1EditFieldLabel           matlab.ui.control.Label
        WG1EditField                matlab.ui.control.NumericEditField
        mLabel                      matlab.ui.control.Label
        mLabel_2                    matlab.ui.control.Label
        WG2EditFieldLabel           matlab.ui.control.Label
        WG2EditField                matlab.ui.control.NumericEditField
        ExportButton                matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        SkipButton                  matlab.ui.control.Button
        WavelengthandcelerityLabel  matlab.ui.control.Label
        PauseButton                 matlab.ui.control.StateButton
        SpeedButton                 matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        CaseconditionsPanel         matlab.ui.container.Panel
        WavePeriodsDropDownLabel    matlab.ui.control.Label
        WavePeriodDropDown          matlab.ui.control.DropDown
        WaterDepthmDropDownLabel    matlab.ui.control.Label
        WaterDepthDropDown          matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        WGAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        caso_nom = '';
        Loaded = false; % Enabled when file is loaded
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        WG1 = 0;
        WG2 = 0;
        
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.LoadButton.Text = 'Load';
                app.LoadButton.Enable = true;
            end
            %clear app.data;
            app.h = app.WaterDepthDropDown.Value;
            app.T = app.WavePeriodDropDown.Value;
            caso = sprintf([num2str(app.T),',',num2str(app.h)]);
            switch caso
                case '2,0.6'   %Caso 1 H=0.15 m; T=2 s; h=0.6
                    app.TituloLabel.Text = "Case 1";
                    app.caso_nom = 'Experiment1/Case1.mat';
                    app.beachX=[0 30 30 0];
                    app.H = '.15';
                    %app.LoadButton.Text = 'Load';
                    %app.LoadButton.Enable = true;
                case '4,0.6'   %Caso 2 H=0.15 m; T=4 s; h=0.6
                    app.TituloLabel.Text = "Case 2";
                    app.caso_nom = 'Experiment1/Case2.mat';
                    app.beachX=[0 30 30 0];
                    app.H = '.15';
                    %app.LoadButton.Text = 'Load';
                    %app.LoadButton.Enable = true;
                case '2,0.4'   %Case 3 H=0.10 m; T=2 s; h=0.4
                    app.TituloLabel.Text = "Case 3";
                    app.caso_nom = 'Experiment1/Case3.mat';
                    app.beachX=[0 30 30 0];
                    app.H = '.1';
                    %app.LoadButton.Text = 'Load';
                    %app.LoadButton.Enable = true;
                case '4,0.4'   %Case 4 H=0.10 m; T=4 s; h=0.4
                    app.TituloLabel.Text = "Case 4";
                    app.caso_nom = 'Experiment1/Case4.mat';
                    app.beachX=[0 30 30 0];
                    app.H = '.1';
                    %app.LoadButton.Text = 'Load';
                    %app.LoadButton.Enable = true;
                otherwise
                    app.TituloLabel.Text = sprintf(['Case does not exist, select other','\n', 'variables']);
                    app.LoadButton.Enable = false;
            end
            string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
            app.DetailsLabel.Text = string;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            if app.h == '0.4'
                yy22=-.4:.2:.6;
            elseif app.h == '0.6'
                yy22=-.6:.2:.4;
            end
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            X = [0 30 30 0];
            Z=[0 0 str2double(app.h) str2double(app.h)];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            
            %Grafica una línea con el periodo
            %Hplot = str2double(app.WaterDepthDropDown.Value) + str2double(app.H);
            %X = [13 18];
            %Z= [Hplot Hplot];
            %plot(app.UIAxes,X,Z,'r');
            %tx= sprintf([app.T,' s']);
            %text(app.UIAxes,15,Hplot, tx, 'VerticalAlignment','bottom') 
            
            xWG1 = app.WG1EditField.Value;
            xWG2 = app.WG2EditField.Value;
            WG(1)=plot(app.UIAxes,[xWG1 xWG1],[str2double(app.h)-0.1 str2double(app.h)+0.20],'k','LineWidth',3);
            WGtext(2)=text(app.UIAxes,xWG1,str2double(app.h)+0.3,'WG1');
            %WGtext(jj)=text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
            WG(2)=plot(app.UIAxes,[xWG2 xWG2],[str2double(app.h)-0.1 str2double(app.h)+0.20],'r','LineWidth',3);
            WGtext(2)=text(app.UIAxes,xWG2,str2double(app.h)+0.3,'WG2');
            
            hold(app.UIAxes,"off");
            app.ExportButton.Enable = false;
        end
        
        function EnableButtons(app,status)
            app.WG1EditField.Enable = status;
            app.WG2EditField.Enable = status;
            app.WavePeriodDropDown.Enable = status;
            app.WaterDepthDropDown.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                %if (app.WG1EditField.Value > 0) || (app.WG1EditField.Value > 0)
                if app.Exportable == true
                    app.ExportButton.Enable = true;
                end
            else
                app.ExportButton.Enable = false;
            end
        end
        
        function PlotGraph(app)
            %app.data = load(app.caso_nom);
            %beachZ=[0 1 0 0];
            %m=length(app.data.xc);
            %n=length(app.data.yc);
            dt=0.1;
            N=length(app.data.time);
            
            xWG1 = app.WG1EditField.Value;
            xWG2 = app.WG2EditField.Value;
           
            if xWG1 > 0
                idxWG1 = find(app.data.xc>xWG1, 1);
                app.WG1 = app.data.eta(:,idxWG1);
                %app.data.eta(:,idxWG1)
            else
                idxWG1 = 0;
            end
            if xWG2 > 0
                idxWG2 = find(app.data.xc>xWG2, 1);
                app.WG2 = app.data.eta(:,idxWG2);
            else
                idxWG2 = 0;
            end
            app.Exportable = true;
            water=[0 0 1];
            j=0;
                
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            
            ylim(app.WGAxes,[-.15 .15]);
            yticks(app.WGAxes,[-.15 0 .15]);
            yticklabels(app.WGAxes,[-.15 0 .15]);
            
            %Inicia la graficacion
            graf=animatedline(app.WGAxes,'Color','k');
            graf2=animatedline(app.WGAxes,'Color','r');
            waves=[];
            for i=0:N-1
                j=j+1;
                
                %Grafica app.UIAxes canal
                if i == 0
                    %beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(j,:) 0], water);
                    hold(app.UIAxes,"on");
                    if xWG1 > 0
                        WG(1)=plot(app.UIAxes,[xWG1 xWG1],[str2double(app.h)-0.1 str2double(app.h)+0.20],'k','LineWidth',3);
                        WGtext(2)=text(app.UIAxes,app.data.xc(idxWG1),str2double(app.h)+0.3,'WG1');
                    end
                    if xWG2 > 0
                        WG(2)=plot(app.UIAxes,[xWG2 xWG2],[str2double(app.h)-0.1 str2double(app.h)+0.20],'r','LineWidth',3);
                        WGtext(2)=text(app.UIAxes,app.data.xc(idxWG2),str2double(app.h)+0.3,'WG2');
                    end
                end
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(j,:) 0], water);
                alpha(waves,.7);
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];

                %Grafica sensores
                if xWG1 > 0
                    addpoints(graf,app.data.time(i+1),app.data.eta(i+1,idxWG1)-str2double(app.h));
                end
                if xWG2 > 0
                    addpoints(graf2,app.data.time(i+1),app.data.eta(i+1,idxWG2)-str2double(app.h));
                end

                %hold(app.UIAxes,"off");
                drawnow;
                
                if i == 0
                    axis(app.WGAxes,[0 10 -0.15 0.15]); %Sustitui app.data.time(i+1)+10 por 10
                elseif mod(app.data.time(i+1),10) == 0
                    axis(app.WGAxes,[app.data.time(i+1) app.data.time(i+1)+10 -0.15 0.15]);
                end
                %hold(app.WGAxes,"off");

                
                %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            end
            legend(app.WGAxes,{'WG1','WG2'},'Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
            %Termina la graficacion
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            
            WaterDepthDropDownValueChanged(app);
            WaveHeightDropDownValueChanged(app);
            WavePeriodDropDownValueChanged(app);
            %Variables disponibles
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.WGAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            %app.StartButton.Enable = false;
            app.skip = false;
            app.WG1 = 0;
            app.WG2 = 0;
            EnableButtons(app,false);            
            
            PlotGraph(app);
            
            EnableButtons(app,true);
        end

        % Value changed function: WaterDepthDropDown
        function WaterDepthDropDownValueChanged(app, event)
            clear app.data;
            app.Loaded = false;
            app.Exportable = false;
            app.h = str2double(app.WaterDepthDropDown.Value);
            UpdateDetails(app);          
        end

        % Callback function
        function WaveHeightDropDownValueChanged(app, event)
%             clear app.data;
%             app.Loaded = false;
%             app.Exportable = false;
%             if app.H == '0.15'
%                 app.WavePeriodDropDown.Value = '2';
%                 app.WaterDepthDropDown.Items = {'0.6'};
%                 app.WaterDepthDropDown.Value = '0.6';
%             elseif app.H == '0.10'
%                 app.WavePeriodDropDown.Value = '2';
%                 app.WaterDepthDropDown.Items = {'0.4'};
%                 app.WaterDepthDropDown.Value = '0.4';
%                 %WavePeriodDropDownValueChanged(app);
%             end
% %             h = str2num(app.WaterDepthDropDown.Value) + str2num(value);
% %             X = [0 30];
% %             Z=[h h];
% %             plot(app.UIAxes,X,Z,'r');
%             UpdateDetails(app);
        end

        % Value changed function: WavePeriodDropDown
        function WavePeriodDropDownValueChanged(app, event)
            clear app.data;
            app.Loaded = false;
            app.Exportable = false;
            app.T = app.WavePeriodDropDown.Value;
            UpdateDetails(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if app.WG1 ~= 0 
                WG1 = app.WG1;
                xWG1 = app.WG1EditField.Value;
            end
            if app.WG2 ~= 0 
                WG2 = app.WG2;
                xWG2 = app.WG2EditField.Value;
            end
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 'WG*','xWG*');
            end
        end

        % Value changed function: WG1EditField
        function WG1EditFieldValueChanged(app, event)
            %value = app.WG1EditField.Value;
            app.Exportable = false;
            app.ExportButton.Enable = false;
            UpdateDetails(app);
        end

        % Value changed function: WG2EditField
        function WG2EditFieldValueChanged(app, event)
            %value = app.WG2EditField.Value;
            app.Exportable = false;
            app.ExportButton.Enable = false;
            UpdateDetails(app);
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            open('Help/Experiment1.pdf');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.Loaded == false
                app.LoadButton.Enable = false;
                EnableButtons(app,false);
                app.LoadButton.Text = "Loading";
                %app.LoadButton.BackgroundColor = [1,1,.07];
                app.LoadButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.LoadButton.BackgroundColor = [0.96,0.96,0.96];
                app.LoadButton.Text = "Loaded";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Numerical Wave Lab - Experiment 1';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CaseinformationPanel = uipanel(app.UIFigure);
            app.CaseinformationPanel.Title = 'Case information:';
            app.DetailsLabel = uilabel(app.CaseinformationPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CaseinformationPanel);
            app.TituloLabel.Text = 'Test:';
            app.Image = uiimage(app.CaseinformationPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'help_bt.png';
            app.Timer = uilabel(app.CaseinformationPanel);
            app.Timer.Text = 't= 0 s';
            app.WGPanel = uipanel(app.UIFigure);
            app.WGPanel.Title = 'Wave gauges location:';
            app.WG1EditFieldLabel = uilabel(app.WGPanel);
            app.WG1EditFieldLabel.Text = 'WG1:';
            app.WG1EditField = uieditfield(app.WGPanel, 'numeric');
            app.WG1EditField.Limits = [0.01 29.99];
            app.WG1EditField.ValueDisplayFormat = '%.2f';
            app.WG1EditField.ValueChangedFcn = createCallbackFcn(app, @WG1EditFieldValueChanged, true);
            app.WG1EditField.Value = 5;
            app.mLabel = uilabel(app.WGPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.WGPanel);
            app.mLabel_2.Text = 'm';
            app.WG2EditFieldLabel = uilabel(app.WGPanel);
            app.WG2EditFieldLabel.Text = 'WG2:';
            app.WG2EditField = uieditfield(app.WGPanel, 'numeric');
            app.WG2EditField.Limits = [0.01 30];
            app.WG2EditField.ValueDisplayFormat = '%.2f';
            app.WG2EditField.ValueChangedFcn = createCallbackFcn(app, @WG2EditFieldValueChanged, true);
            app.WG2EditField.Value = 8;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.WavelengthandcelerityLabel = uilabel(app.UIFigure);
            app.WavelengthandcelerityLabel.Text = 'Wave length and celerity';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.CaseconditionsPanel = uipanel(app.UIFigure);
            app.CaseconditionsPanel.Title = 'Case conditions:';
            app.WavePeriodsDropDownLabel = uilabel(app.CaseconditionsPanel);
            app.WavePeriodsDropDownLabel.Text = 'Wave Period (s)';
            app.WavePeriodDropDown = uidropdown(app.CaseconditionsPanel);
            app.WavePeriodDropDown.Items = {'2', '4'};
            app.WavePeriodDropDown.ValueChangedFcn = createCallbackFcn(app, @WavePeriodDropDownValueChanged, true);
            app.WavePeriodDropDown.Value = '2';
            app.WaterDepthmDropDownLabel = uilabel(app.CaseconditionsPanel);
            app.WaterDepthmDropDownLabel.Text = 'Water Depth (m)';
            app.WaterDepthDropDown = uidropdown(app.CaseconditionsPanel);
            app.WaterDepthDropDown.Items = {'0.6', '0.4'};
            app.WaterDepthDropDown.ValueChangedFcn = createCallbackFcn(app, @WaterDepthDropDownValueChanged, true);
            app.WaterDepthDropDown.Value = '0.6';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Flume')
            xlabel(app.UIAxes, 'Along-flume distance, x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Free surface elevation')
            xlabel(app.WGAxes, 'Time (s)')
            ylabel(app.WGAxes, '\eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            app.WGAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end