classdef Experiment3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CaseinformationPanel  matlab.ui.container.Panel
        DetailsLabel          matlab.ui.control.Label
        TituloLabel           matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        Timer                 matlab.ui.control.Label
        ExportButton          matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        SkipButton            matlab.ui.control.Button
        TitleLabel            matlab.ui.control.Label
        PauseButton           matlab.ui.control.StateButton
        SpeedButton           matlab.ui.control.Button
        WaveTypeButtonGroup   matlab.ui.container.ButtonGroup
        ProgressiveButton     matlab.ui.control.RadioButton
        StationaryButton      matlab.ui.control.RadioButton
        ParticlePanel         matlab.ui.container.Panel
        xpositionofpressuresensorPS1andwavegaugeWG1Label  matlab.ui.control.Label
        XSensorsEditField     matlab.ui.control.NumericEditField
        xmLabel_4             matlab.ui.control.Label
        zmLabel_5             matlab.ui.control.Label
        zpositionofpressuresensorPS1Label  matlab.ui.control.Label
        ZSensorEditField      matlab.ui.control.NumericEditField
        LoadButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        WGAxes                matlab.ui.control.UIAxes
        PSAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        caso_nom = '';
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        WG1 = 0;
        %WG2 = 0;
        Loaded = false; % Cambia cuando se ha cargado el .mat
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        P1 = 0; % Variable para exportar
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.LoadButton.Text = 'Load';
                app.LoadButton.Enable = true;
            end
            %clear app.data;

            caso = app.WaveTypeButtonGroup.SelectedObject.Text;
%             caso = sprintf([num2str(H),',',num2str(T),',',num2str(h)]);
            switch caso
                case 'Stationary' %H=0.15 m; T=2 s; h=0.6 m
                    app.TituloLabel.Text = "Stationary Wave";
                    app.caso_nom = 'Experiment3/Case1.mat';
                    app.beachX=[0 30 30 0];
                    app.H=0.15; app.T=2; app.h=0.6;
                    %string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
                case 'Progressive' %H=0.075 m; T=2 s; h=0.6 m
                    app.TituloLabel.Text = "Progressive Wave";
                    app.caso_nom = 'Experiment3/Case2.mat';
                    app.beachX=[0 30 30 0];
                    app.H=0.075; app.T=2; app.h=0.6;
%                     string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
                otherwise
                    app.TituloLabel.Text = 'Error, select wave type';
            end
            %string = sprintf(['H= ',H,' m','\n','h= ',h,' m','\n','T= ',T,' s']);
            string = sprintf(['H= ',num2str(app.H),' m','\n','h= ',num2str(app.h),' m','\n','T= ',num2str(app.T),' s']);
            app.DetailsLabel.Text = string;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            yy22=-.6:.2:.4;
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            
            X = [0 30 30 0];
            Z=[0 0 app.h app.h];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            
            X = app.XSensorsEditField.Value;
            Z = app.ZSensorEditField.Value;
            WG=plot(app.UIAxes,[X X],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                    WGtext=text(app.UIAxes,X+0.5,0.75,'WG1');
            PS=[plot(app.UIAxes,X,app.h+Z,'ok','MarkerFaceColor','r')...
                    text(app.UIAxes,X+0.5,app.h+Z+0.25,'PS1','color','red')];
            
            hold(app.UIAxes,"off");
            
            title(app.WGAxes, ['Free surface elevation at x=',num2str(app.XSensorsEditField.Value)]);
            title(app.PSAxes, ['Fluid velocity at x=',num2str(app.XSensorsEditField.Value),' and y=',num2str(app.ZSensorEditField.Value)]);
            
            app.ExportButton.Enable = false;
        end
        
        function EnableButtons(app,status)
            app.XSensorsEditField.Enable = status;
            app.ZSensorEditField.Enable = status;
            app.ProgressiveButton.Enable = status;
            app.StationaryButton.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                %if (app.XSensorsEditField.Value > 0) || (app.ZSensorEditField.Value > 0)
                if app.Exportable == true
                    app.ExportButton.Enable = true;
                end
            else
                app.ExportButton.Enable = false;
            end
        end
        
        function PlotGraph(app)
            P1X = app.XSensorsEditField.Value;
            P1Z = app.h + app.ZSensorEditField.Value;
            %Encuentra indice de las posiciones que corresponden a los indices
            idx=find(app.data.xc>P1X, 1 );
            idx0=idx;
            
            idy=find(app.data.yc>P1Z, 1 );
            %idy0=idy;
            
            %Extrae serie temporal de la matriz tridimensional de las componentes de la
            %velocidad

            P1(:)=1000.*(app.data.P(idy,idx,:)+0.049);
            P1(1)=P1(2);
            app.data.eta=app.data.eta-0.005;
            
            dt=0.1;
            water=[0 0 1];
            i0=1;
            X(i0)=app.data.xc(idx);
            Y(i0)=app.data.yc(idy);
            
            app.Exportable = true;
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            xlabel(app.PSAxes,'Time (s)');
            ylabel(app.PSAxes,'p (Pa)');
            
            %Orden de Colores
            %1. 'k'
            %2. [0, 0.4470, 0.7410]
            %3. [0.8500, 0.3250, 0.0980]
            %4. [0.9290, 0.6940, 0.1250]
            %5. [0.4940, 0.1840, 0.5560]
            %6. [0.4660, 0.6740, 0.1880]
            %7. [0.3010, 0.7450, 0.9330]
            %8. [0.6350, 0.0780, 0.1840]
            
            %Inicia la Grafricacion
            WG_graf1=animatedline(app.WGAxes,'Color','k');
            PS_graf2=animatedline(app.PSAxes,'Color','r');
            waves=[];
            PS=[];
            %Inicia la Grafricacion
            for i=2:1000
                
                %Grafica app.UIAxes canal y series temporales de superficie libre, u y w
                %Grafica app.UIAxes canal
                if i == 2
                    %beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    %waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(j,:) 0], water);
                    WG=plot(app.UIAxes,[app.data.xc(idx0) app.data.xc(idx0)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                    hold(app.UIAxes,"on"); 
                    WGtext=text(app.UIAxes,app.data.xc(idx0)+0.5,0.75,'WG1');
                    %PS=[plot(app.UIAxes,app.data.xc(idx),app.data.yc(idy),'ok','MarkerFaceColor','r')...
                    %   text(app.UIAxes,app.data.xc(idx0)+0.5,app.data.yc(idy)+0.25,'PS1','color','red')];
                end
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                alpha(waves,.7);
                delete(PS);
                PS=[plot(app.UIAxes,app.data.xc(idx),app.data.yc(idy),'ok','MarkerFaceColor','r')...
                    text(app.UIAxes,app.data.xc(idx0)+0.5,app.data.yc(idy)+0.25,'PS1','color','red')];
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];
                
                %Grafica WGAxes
                %plot(app.WGAxes,app.data.time(1:i),app.data.eta(1:i,idx0)-app.h,'k');
                addpoints(WG_graf1,app.data.time(i),app.data.eta(i,idx0)-app.h);
                %hold(app.WGAxes,"on");
                if i == 2
                    axis(app.WGAxes,[0 10 -0.15 0.15]);
                elseif mod(app.data.time(i+1),10) == 0
                    axis(app.WGAxes,[app.data.time(i+1) app.data.time(i+1)+10 -0.15 0.15]);
                end 
                %axis(app.WGAxes,[app.data.time(i+1)-20 app.data.time(i+1) -0.15 0.15]);
                %hold(app.WGAxes,"off");
                
                %Grafica PSAxes
                %plot(app.PSAxes,app.data.time(1:i),P1(1:i),'r');
                addpoints(PS_graf2,app.data.time(i),P1(i));
                if i == 2
                    axis(app.PSAxes,[0 10 min(P1)-200 max(P1)+200]);
                elseif mod(app.data.time(i+1),10) == 0
                    axis(app.PSAxes,[app.data.time(i+1) app.data.time(i+1)+10 min(P1)-200 max(P1)+200]);
                end
                
                
                drawnow;
                
                
               %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            
                
            end
            legend(app.WGAxes,"WG1",'Location','southwest');
            legend(app.PSAxes,"PS1",'Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
%             %Termina la graficacion

            %Variables a exportar
            app.WG1=app.data.eta(:,idx0);
            app.P1 = P1;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            app.PSAxes.ZLabel.String = "w (m/s)";
            WaveTypeButtonGroupSelectionChanged(app);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            title(app.WGAxes, ['Free surface elevation at x=',num2str(app.XSensorsEditField.Value)]);
            title(app.PSAxes, ['Fluid velocity at x=',num2str(app.XSensorsEditField.Value),' and y=',num2str(app.ZSensorEditField.Value)]);
            xlabel(app.PSAxes,'Time (s)');
            ylabel(app.PSAxes,'p (Pa)');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.WGAxes);
            cla(app.PSAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            legend(app.PSAxes,"off");
            app.PSAxes.Toolbar.Visible = 'off';
            if app.Loaded
                %app.StartButton.Enable = false;
                app.skip = false;
                %app.WG1 = 0;
                %app.WG2 = 0;
                EnableButtons(app,false);            
                PlotGraph(app);            
                EnableButtons(app,true); 
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            P1X = app.XSensorsEditField.Value;
            P1Z = app.ZSensorEditField.Value;
            WG1 = app.WG1;
            idx=find(app.data.xc>P1X, 1 );
            idy=find(app.data.yc>P1Z, 1 );
            P1=app.P1;

            time = app.data.time;
            
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 'P1X', 'P1Z', 'WG1', 'P1','time');
            end
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Selection changed function: WaveTypeButtonGroup
        function WaveTypeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.WaveTypeButtonGroup.SelectedObject;
            clear app.data;
            app.Exportable = false;
            app.Loaded = false;
            app.LoadButton.Text = 'Load';
            app.LoadButton.Enable = true;
            UpdateDetails(app);
        end

        % Value changed function: XSensorsEditField
        function XSensorsEditFieldValueChanged(app, event)
            %value = app.XSensorsEditField.Value;
            UpdateDetails(app);
            app.Exportable = false;
            app.ExportButton.Enable = false;
        end

        % Callback function
        function ZSensorEditFieldValueChanged(app, event)
            %value = app.ZSensorEditField.Value;
            UpdateDetails(app);
            app.Exportable = false;
            app.ExportButton.Enable = false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.Loaded == false
                app.LoadButton.Enable = false;
                EnableButtons(app,false);
                app.LoadButton.Text = "Loading";
                %app.LoadButton.BackgroundColor = [1,1,.07];
                app.LoadButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.LoadButton.BackgroundColor = [0.96,0.96,0.96];
                app.LoadButton.Text = "Loaded";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            open('Help/Experiment3.pdf');
        end

        % Value changed function: ZSensorEditField
        function ZSensorEditFieldValueChanged2(app, event)
            %value = app.ZSensorEditField.Value;
            UpdateDetails(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Numerical Wave Lab - Experiment 3';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CaseinformationPanel = uipanel(app.UIFigure);
            app.CaseinformationPanel.Title = 'Case information:';
            app.DetailsLabel = uilabel(app.CaseinformationPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CaseinformationPanel);
            app.TituloLabel.Text = 'Test:';
            app.Image = uiimage(app.CaseinformationPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'help_bt.png';
            app.Timer = uilabel(app.CaseinformationPanel);
            app.Timer.Text = 't= 0 s';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = ' Pressure variation beneath progressive and stationary waves';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.WaveTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WaveTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaveTypeButtonGroupSelectionChanged, true);
            app.WaveTypeButtonGroup.Title = 'Wave Type:';
            app.ProgressiveButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.ProgressiveButton.Text = 'Stationary';
            app.ProgressiveButton.Value = true;
            app.StationaryButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.StationaryButton.Text = 'Progressive';
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Sensors location:';
            app.xpositionofpressuresensorPS1andwavegaugeWG1Label = uilabel(app.ParticlePanel);
            app.xpositionofpressuresensorPS1andwavegaugeWG1Label.Text = {'x position of pressure sensor   '; '(PS1) and wave gauge (WG1) ='};
            app.XSensorsEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.XSensorsEditField.Limits = [0.01 29.99];
            app.XSensorsEditField.ValueDisplayFormat = '%.2f';
            app.XSensorsEditField.ValueChangedFcn = createCallbackFcn(app, @XSensorsEditFieldValueChanged, true);
            app.XSensorsEditField.Value = 10;
            app.xmLabel_4 = uilabel(app.ParticlePanel);
            app.xmLabel_4.Text = 'm';
            app.zmLabel_5 = uilabel(app.ParticlePanel);
            app.zmLabel_5.Text = 'm';
            app.zpositionofpressuresensorPS1Label = uilabel(app.ParticlePanel);
            app.zpositionofpressuresensorPS1Label.Text = {'z position of pressure sensor   '; '(PS1) ='};
            app.ZSensorEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.ZSensorEditField.Limits = [-0.6 0];
            app.ZSensorEditField.ValueDisplayFormat = '%.2f';
            app.ZSensorEditField.ValueChangedFcn = createCallbackFcn(app, @ZSensorEditFieldValueChanged2, true);
            app.ZSensorEditField.Value = -0.6;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Flume')
            xlabel(app.UIAxes, 'Along-flume distance, x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Free surface elevation')
            ylabel(app.WGAxes, 'eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            app.PSAxes = uiaxes(app.UIFigure);
            title(app.PSAxes, 'Pressure')
            xlabel(app.PSAxes, 'Time (s)')
            ylabel(app.PSAxes, 'p (Pa)')
            app.PSAxes.YLim = [-0.15 0.15];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end