classdef Menu_CN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuUIFigure             matlab.ui.Figure
        Version001Label          matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ExperimentsListBoxLabel  matlab.ui.control.Label
        ExperimentsListBox       matlab.ui.control.ListBox
        RunButton                matlab.ui.control.Button
        LabAssignmentButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        option = false; % Guarda la opción seleccionada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MenuUIFigure.Visible = 'off';
            movegui(app.MenuUIFigure,"center");
            app.MenuUIFigure.Visible = 'on';
            ExperimentsListBoxValueChanged(app);
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            app.option = app.ExperimentsListBox.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %app.option = app.ExperimentsListBox.ItemsData.Value
            switch app.option
                case 1
                    Experiment1;
                case 2
                    Experiment2;
                case 3
                    Experiment3;
                case 4
                    Experiment4;
                case 5
                    Experiment5;
                case 6
                    Experiment6;
                case 7
                    Experiment7;
                case 8
                    Experiment8;
            end
            
            close(app.MenuUIFigure);
        end

        % Button pushed function: LabAssignmentButton
        function LabAssignmentButtonPushed(app, event)
            switch app.option
                case 1
                    open('Help/Experiment1.pdf');
                case 2
                    open('Help/Experiment2.pdf');
                case 3
                    open('Help/Experiment3.pdf');
                case 4
                    open('Help/Experiment4.pdf');
                case 5
                    open('Help/Experiment5.pdf');
                case 6
                    open('Help/Experiment6.pdf');
                case 7
                    open('Help/Experiment7.pdf');
                case 8
                    open('Help/Experiment8.pdf');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuUIFigure = uifigure('Visible', 'off');
            app.MenuUIFigure.Name = 'Numerical Wave Lab';
            app.Version001Label = uilabel(app.MenuUIFigure);
            app.Version001Label.Text = 'Version 0.01';
            app.Image = uiimage(app.MenuUIFigure);
            app.Image.ImageSource = 'Splash_WaveFlume.png';
            app.ExperimentsListBoxLabel = uilabel(app.MenuUIFigure);
            app.ExperimentsListBoxLabel.Text = 'Experiments:';
            app.ExperimentsListBox = uilistbox(app.MenuUIFigure);
            app.ExperimentsListBox.Items = {'1. Wave length and celerity', '2. Wave-induced velocities and particle trajectories', '3. Pressure variations beneath progressive and stationary waves', '4. Wave reflection from beaches', '5. Wave reflection from a partial vertical barrier', '6. Wave shoaling and breaking', '7. Irregular wave breaking', '8. Wave set-down and set-up'};
            app.ExperimentsListBox.ItemsData = [1 2 3 4 5 6 7 8];
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Tooltip = {'Select an option'};
            app.ExperimentsListBox.Value = 1;
            app.RunButton = uibutton(app.MenuUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'Icon_WaveFlume.png';
            app.RunButton.Tooltip = {'Run experiment'};
            app.RunButton.Text = 'Run';
            app.LabAssignmentButton = uibutton(app.MenuUIFigure, 'push');
            app.LabAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @LabAssignmentButtonPushed, true);
            app.LabAssignmentButton.Icon = 'Document_bt.jpeg';
            app.LabAssignmentButton.Tooltip = {'View Lab Assignment'};
            app.LabAssignmentButton.Text = 'Lab Assignment';
            % Show the figure after all components are created
            app.MenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu_CN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuUIFigure)
        end
    end
end